classdef FeeSkinnerUffer_Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChooseMethodLabel              matlab.ui.control.Label
        ChooseMethodDropDown           matlab.ui.control.DropDown
        UploadFileButton               matlab.ui.control.Button
        TestButton                     matlab.ui.control.Button
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        StoppingMethodDropDownLabel    matlab.ui.control.Label
        StoppingMethodDropDown         matlab.ui.control.DropDown
        AnswerLabel                    matlab.ui.control.Label
        WilldisplayChoiceMatrixThresholdLabel  matlab.ui.control.Label
        InConsoleWindowLabel           matlab.ui.control.Label
        CMPT34902FinalProjectLabel     matlab.ui.control.Label
        SolveaSystemofLinearEquationsGaussSeidelandJacobiMethodLabel  matlab.ui.control.Label
        AugmentedMatrixLabel           matlab.ui.control.Label
        AugmentedMatrixEditField       matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        NumberofColumnsEditFieldLabel  matlab.ui.control.Label
        NumberofColumnsEditField       matlab.ui.control.NumericEditField
        OruploadafilewhichisintheformatLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        WrittenbyMaireadFeeSamuelSkinnerKatherineUfferLabel  matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        TrueMeanAbsoluteErrorLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        enteredMatrix;
        method = 1;
        columns = 1; %default 1 so no errors if inputing matrix before columns
        threshold = .001;
        solutionMatrix;
        stoppingMethod = 1;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function MethodButtonGroupSelectionChanged(app, event)
        %DOESNT WORK    
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
                if isequal(file,0)
                    disp('User selected Cancel');
                    data_table = [];
                else
                    data_table = readtable(fullfile(path,file));
                end
                
                app.enteredMatrix = table2array(data_table);
                
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           %display selected method, the augmented matrix, and threshhold
           y = app.method;
           disp("Method Chosen");
           disp(y);
           z = app.enteredMatrix;
           disp("Matrix");
           disp(z);
           a = app.threshold;
           disp("Threshhold");
           disp(a);
           
        end

        % Value changed function: ChooseMethodDropDown
        function ChooseMethodDropDownValueChanged(app, event)
            value = app.ChooseMethodDropDown.Value;
       
            if value == "Gauss-Seidel" 
                app.method = 1;
            elseif value == "Jacobi"
                app.method = 2;
            else 
                app.method = 0;
            end
                
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.threshold = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.method == 1) %if Gauss-Seidel is chosen
               app.solutionMatrix = ufferGaussSeidel(app.enteredMatrix,app.threshold, app.stoppingMethod); %find solution
               app.AnswerLabel.Text = string(app.solutionMatrix); %display solution in GUI
               
            elseif (app.method == 2)%if Jacobi is chosen
                app.solutionMatrix = feeJacobi(app.enteredMatrix,app.threshold, app.stoppingMethod); %find solution
                app.AnswerLabel.Text = string(app.solutionMatrix); %display in GUI
            else
                app.AnswerLabel.Text = "No Method Chosen!"; %display no method chosen (can never be reached)
            end
            
            %disp(trueError(app.enteredMatrix,app.solutionMatrix))
            app.TrueMeanAbsoluteErrorLabel.Text = string(trueError(app.enteredMatrix,app.solutionMatrix)); %print true mean absolute error
            
        end

        % Value changed function: StoppingMethodDropDown
        function StoppingMethodDropDownValueChanged(app, event)
            value = app.StoppingMethodDropDown.Value;
            
            if value == "MAE"
                app.stoppingMethod = 1;
            elseif value == "RMSE"
                app.stoppingMethod = 2;
            else 
                app.stoppingMethod = 0;
            end
            
        end

        % Callback function
        function SolutionTextAreaValueChanged(app, event)
         %May or may not ever work
%             msg = string(app.solutionMatrix);
%             disp(msg);
%             app.SolutionTextArea.Text = msg;
        end

        % Value changed function: AugmentedMatrixEditField
        function AugmentedMatrixEditFieldValueChanged(app, event)
            value = app.AugmentedMatrixEditField.Value;
            B = strrep(value,';',',');
            C = char(strsplit(B));
            app.enteredMatrix = reshape(str2num(C), app.columns, [])'; %create number array with user inputted number of columns
            
        end

        % Value changed function: NumberofColumnsEditField
        function NumberofColumnsEditFieldValueChanged(app, event)
            app.columns = app.NumberofColumnsEditField.Value; %change number of columns
            
            %adjust matrix if there is one
            value = app.AugmentedMatrixEditField.Value;
            B = strrep(value,';',',');
            C = char(strsplit(B));
            app.enteredMatrix = reshape(str2num(C), app.columns, [])'; %create number array with user inputted number of columns
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseMethodLabel = uilabel(app.UIFigure);
            app.ChooseMethodLabel.Text = 'Choose Method:';
            app.ChooseMethodDropDown = uidropdown(app.UIFigure);
            app.ChooseMethodDropDown.Items = {'Gauss-Seidel', 'Jacobi'};
            app.ChooseMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMethodDropDownValueChanged, true);
            app.ChooseMethodDropDown.Value = 'Gauss-Seidel';
            app.UploadFileButton = uibutton(app.UIFigure, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = ' Test';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.001;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.StoppingMethodDropDownLabel = uilabel(app.UIFigure);
            app.StoppingMethodDropDownLabel.Text = 'Stopping Method';
            app.StoppingMethodDropDown = uidropdown(app.UIFigure);
            app.StoppingMethodDropDown.Items = {'MAE', 'RMSE'};
            app.StoppingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @StoppingMethodDropDownValueChanged, true);
            app.StoppingMethodDropDown.Value = 'MAE';
            app.AnswerLabel = uilabel(app.UIFigure);
            app.AnswerLabel.Text = 'Answer';
            app.WilldisplayChoiceMatrixThresholdLabel = uilabel(app.UIFigure);
            app.WilldisplayChoiceMatrixThresholdLabel.Text = 'Will display: Choice, Matrix, Threshold';
            app.InConsoleWindowLabel = uilabel(app.UIFigure);
            app.InConsoleWindowLabel.Text = 'In Console Window';
            app.CMPT34902FinalProjectLabel = uilabel(app.UIFigure);
            app.CMPT34902FinalProjectLabel.Text = 'CMPT349-02 Final Project:';
            app.SolveaSystemofLinearEquationsGaussSeidelandJacobiMethodLabel = uilabel(app.UIFigure);
            app.SolveaSystemofLinearEquationsGaussSeidelandJacobiMethodLabel.Text = 'Solve a System of Linear Equations using Gauss-Seidel or Jacobi Method';
            app.AugmentedMatrixLabel = uilabel(app.UIFigure);
            app.AugmentedMatrixLabel.Text = 'Augmented Matrix:';
            app.AugmentedMatrixEditField = uieditfield(app.UIFigure, 'text');
            app.AugmentedMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @AugmentedMatrixEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Please input the augmented matrix in the format 1,2,3,4;5,6,7,8 and number of columns.';
            app.NumberofColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofColumnsEditFieldLabel.Text = 'Number of Columns:';
            app.NumberofColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofColumnsEditFieldValueChanged, true);
            app.NumberofColumnsEditField.Value = 1;
            app.OruploadafilewhichisintheformatLabel = uilabel(app.UIFigure);
            app.OruploadafilewhichisintheformatLabel.Text = 'Or upload a file which is in the format: ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1,2,3,4';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '5,6,7,8';
            app.WrittenbyMaireadFeeSamuelSkinnerKatherineUfferLabel = uilabel(app.UIFigure);
            app.WrittenbyMaireadFeeSamuelSkinnerKatherineUfferLabel.Text = 'Written by: Mairead Fee, Samuel Skinner, Katherine Uffer';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '_____________________________________________________________________________________';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '_____________________________________________________________________________________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '_____________________________________________________________________________________';
            app.TrueMeanAbsoluteErrorLabel = uilabel(app.UIFigure);
            app.TrueMeanAbsoluteErrorLabel.Text = 'True Mean Absolute Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeeSkinnerUffer_Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end